classdef pin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PINDisplayField                matlab.ui.control.EditField
        PINDisplayFieldEditFieldLabel  matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
        Button_10                      matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        Button_9                       matlab.ui.control.Button
        Button_8                       matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        Button_6                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
    end

properties (Access = private)
    pin_input = [];  % Array to hold entered digits
end

methods (Access = private)
    function updateDisplay(app)
        if isempty(app.pin_input)
            app.PINDisplayField.Value = '';  % Clear display if no input
        else
            % Show digits separated by spaces (or mask with '*')
            app.PINDisplayField.Value = strjoin(string(app.pin_input), ' ');
            % To mask input: uncomment below and comment above
            % app.PINDisplayField.Value = repmat('* ', 1, length(app.pin_input));
        end
    end
    function showAccessResult(~, granted)
    % Create a new figure window
    resultFig = uifigure('Name', 'Result', 'Position', [500 500 300 150]);

    if granted == 1
        msg = '✅ ACCESS GRANTED';
        color = [0.2 0.8 0.2];  % Green
    else
        msg = '❌ ACCESS DENIED';
        color = [0.9 0.2 0.2];  % Red
    end

    % Display label
    uilabel(resultFig, ...
        'Text', msg, ...
        'FontSize', 18, ...
        'FontWeight', 'bold', ...
        'HorizontalAlignment', 'center', ...
        'Position', [25 60 250 40], ...
        'FontColor', color);
end

    function addDigit(app, digit)
        if length(app.pin_input) < 4
            app.pin_input(end+1) = digit;  % Append digit
            app.updateDisplay();
        end
    end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Button_9Pushed(app, event)
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed2(app, event)
        if length(app.pin_input) ~= 4
        uialert(app.UIFigure, 'Enter a 4-digit PIN.', 'Error');
        return;
    end

    % Assign PIN to base workspace for Simulink
    assignin('base', 'entered_pin', app.pin_input);

    try
        % Run Simulink model (make sure 'smart_door_sim' is open or on path)
        simOut = sim('smart_door_sim');

        % Get logged signal
        logs = simOut.logsout;
        access_signal = logs.getElement('access_granted').Values.Data;

        % Get the last value
        result = access_signal(end);

        % Show a new window with result
        showAccessResult(result);

    catch ME
        uialert(app.UIFigure, ['Simulation error: ' ME.message], 'Error');
    end

    % Reset PIN
    app.pin_input = [];
    app.updateDisplay();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed2(app, event)
             app.pin_input = [];
        app.updateDisplay();
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            app.addDigit(0);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             app.addDigit(1);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
             app.addDigit(2);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             app.addDigit(3);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             app.addDigit(4);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
             app.addDigit(5);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             app.addDigit(6);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
             app.addDigit(7);
        end

        % Button pushed function: Button_9
        function Button_9Pushed2(app, event)
             app.addDigit(8);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
             app.addDigit(9);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '0';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '1';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '2';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '3';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '4';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '5';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '6';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '7';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed2, true);
            app.Button_9.Text = '8';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed2, true);
            app.ClearButton.Text = 'Clear';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '9';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed2, true);
            app.EnterButton.Text = 'Enter';
            app.PINDisplayFieldEditFieldLabel = uilabel(app.UIFigure);
            app.PINDisplayFieldEditFieldLabel.Text = 'PINDisplayField';
            app.PINDisplayField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end